classdef SignalVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalVisualizerUIFigure      matlab.ui.Figure
        SignalVisualizerMenu          matlab.ui.container.Menu
        SalirMenu                     matlab.ui.container.Menu
        GenerarMenu                   matlab.ui.container.Menu
        TonopuroMenu                  matlab.ui.container.Menu
        SntesisdearmnicosMenu         matlab.ui.container.Menu
        SealesperidicasconocidasMenu  matlab.ui.container.Menu
        OndacuadradaMenu              matlab.ui.container.Menu
        DientedesierraMenu            matlab.ui.container.Menu
        PulsodeRosenbergMenu          matlab.ui.container.Menu
        RuidoMenu                     matlab.ui.container.Menu
        IdiomaMenu                    matlab.ui.container.Menu
        InglsMenu                     matlab.ui.container.Menu
        CastellanoMenu                matlab.ui.container.Menu
        EuskeraMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DialogApp % Description
        PlotApp1
        PlotApp2
        PlotApp3
        PlotApp4
        time % Description
        fs = 48000; % Description
        language = 1; % Description
        plotAppArray % Description
        wMax % Númer omáximo de ventanas
    end
    
    properties (Access = public)
        signal = []; % Description
        spectrum = []; % Description
        freq = []; % Description
        plotApp_counter
    end
    
    methods (Access = public)
        
        function success = createPlot(app,signal,t,varargin)
            
            % Comprueba si ya están abiertas el número máximo posible de
            % ventanas
            if app.plotApp_counter == app.wMax
                success = 0;
                return
            end
            
            % Busca una variable PlotApp vacía o de una ventana ya cerrada
            % para crear una nueva
            for i = 1:app.wMax
                if isempty(app.(app.plotAppArray{i}))
                    app.(app.plotAppArray{i}) = visualization_App(app,app.language,signal,t,varargin{:});
                    app.plotApp_counter = app.plotApp_counter + 1;
                    break
                elseif ~isvalid(app.(app.plotAppArray{i}))
                    app.(app.plotAppArray{i}) = visualization_App(app,app.language,signal,t,varargin{:});
                    app.plotApp_counter = app.plotApp_counter + 1;
                    break
                end
            end
            
            success = 1;
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 634;
            originalh = 554;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.SignalVisualizerUIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(~,element)
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalw = element.Position(3);
            originalh = element.Position(4);
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            newhpos = round(originalhpos*screenw/1280);
            newvpos = round(originalvpos*screenh/1024);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(screenw*screenh)/(1280*1024));
            
        end
        
        function translate(app,language)
            load('language.mat','main')
            
            app.SalirMenu.Text = main.exit{language};
            app.GenerarMenu.Text = main.generate{language};
            app.TonopuroMenu.Text = main.pure_tone{language};
            app.SntesisdearmnicosMenu.Text = main.harmonics_synthesis{language};
            app.SealesperidicasconocidasMenu.Text = main.known_periodic_signals{language};
            app.OndacuadradaMenu.Text = main.square_wave{language};
            app.DientedesierraMenu.Text = main.sawtooth_wave{language};
            app.PulsodeRosenbergMenu.Text = main.rosenberg_pulse{language};
            app.RuidoMenu.Text = main.noise{language};
            app.IdiomaMenu.Text = main.language{language};
            app.InglsMenu.Text = main.english{language};
            app.CastellanoMenu.Text = main.spanish{language};
            app.EuskeraMenu.Text = main.basque{language};
            
            clear('main')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resizeWindow(app);
            
            for i = 1:length(app.SignalVisualizerUIFigure.Children)
                if ~strcmp(app.SignalVisualizerUIFigure.Children(i).Type,'uimenu')
                    app.resizeelement(app.SignalVisualizerUIFigure.Children(i));
                end
            end
            
            load('lang_ind.mat','lang_ind')
            
            if exist('lang_ind','var') ~= 1
                lang_ind = 1;
                save('lang_ind.mat','lang_ind')
            end
            
            app.language = lang_ind;
            app.translate(app.language);
            
            app.plotApp_counter = 0;
            app.plotAppArray = {'PlotApp1','PlotApp2','PlotApp3','PlotApp4'};
            app.wMax = 4;
            
            app.fs = 48000;
        end

        % Menu selected function: TonopuroMenu
        function TonopuroMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = tone_App(app,app.language);
        end

        % Close request function: SignalVisualizerUIFigure
        function SignalVisualizerUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            
            for i = 1:app.wMax
                if ~isempty(app.(app.plotAppArray{i}))
                    if isvalid(app.(app.plotAppArray{i}))
                        delete(app.(app.plotAppArray{i}))
                    end
                end
            end
            
            delete(app)
        end

        % Menu selected function: SntesisdearmnicosMenu
        function SntesisdearmnicosMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = harmonics_synthesis_App(app,app.language);
        end

        % Menu selected function: OndacuadradaMenu
        function OndacuadradaMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = square_App(app,app.language);
        end

        % Menu selected function: DientedesierraMenu
        function DientedesierraMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = sawtooth_App(app,app.language);
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            delete(app.DialogApp);
            
            delete(app)
        end

        % Menu selected function: PulsodeRosenbergMenu
        function PulsodeRosenbergMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = rosenberg_App(app,app.language);
        end

        % Menu selected function: RuidoMenu
        function RuidoMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            
            
            app.DialogApp = noise_App(app,app.language);
        end

        % Menu selected function: InglsMenu
        function InglsMenuSelected(app, event)
            app.language = 1;
            app.translate(app.language);
            
            lang_ind = 1;
            save('lang_ind.mat','lang_ind')
        end

        % Menu selected function: CastellanoMenu
        function CastellanoMenuSelected(app, event)
            app.language = 2;
            app.translate(app.language);
            
            lang_ind = 2;
            save('lang_ind.mat','lang_ind')
        end

        % Menu selected function: EuskeraMenu
        function EuskeraMenuSelected(app, event)
            app.language = 3;
            app.translate(app.language);
            
            lang_ind = 3;
            save('lang_ind.mat','lang_ind')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalVisualizerUIFigure = uifigure('Visible', 'off');
            app.SignalVisualizerUIFigure.Name = 'Signal Visualizer';
            app.SignalVisualizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @SignalVisualizerUIFigureCloseRequest, true);
            app.SignalVisualizerMenu = uimenu(app.SignalVisualizerUIFigure);
            app.SignalVisualizerMenu.Text = 'Signal Visualizer';
            app.SalirMenu = uimenu(app.SignalVisualizerMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.GenerarMenu = uimenu(app.SignalVisualizerUIFigure);
            app.GenerarMenu.Text = 'Generar';
            app.TonopuroMenu = uimenu(app.GenerarMenu);
            app.TonopuroMenu.MenuSelectedFcn = createCallbackFcn(app, @TonopuroMenuSelected, true);
            app.TonopuroMenu.Text = 'Tono puro';
            app.SntesisdearmnicosMenu = uimenu(app.GenerarMenu);
            app.SntesisdearmnicosMenu.MenuSelectedFcn = createCallbackFcn(app, @SntesisdearmnicosMenuSelected, true);
            app.SntesisdearmnicosMenu.Text = 'Síntesis de armónicos';
            app.SealesperidicasconocidasMenu = uimenu(app.GenerarMenu);
            app.SealesperidicasconocidasMenu.Text = 'Señales periódicas conocidas';
            app.OndacuadradaMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.OndacuadradaMenu.MenuSelectedFcn = createCallbackFcn(app, @OndacuadradaMenuSelected, true);
            app.OndacuadradaMenu.Text = 'Onda cuadrada';
            app.DientedesierraMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.DientedesierraMenu.MenuSelectedFcn = createCallbackFcn(app, @DientedesierraMenuSelected, true);
            app.DientedesierraMenu.Text = 'Diente de sierra';
            app.PulsodeRosenbergMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.PulsodeRosenbergMenu.MenuSelectedFcn = createCallbackFcn(app, @PulsodeRosenbergMenuSelected, true);
            app.PulsodeRosenbergMenu.Text = 'Pulso de Rosenberg';
            app.RuidoMenu = uimenu(app.GenerarMenu);
            app.RuidoMenu.MenuSelectedFcn = createCallbackFcn(app, @RuidoMenuSelected, true);
            app.RuidoMenu.Text = 'Ruido';
            app.IdiomaMenu = uimenu(app.SignalVisualizerUIFigure);
            app.IdiomaMenu.Text = 'Idioma';
            app.InglsMenu = uimenu(app.IdiomaMenu);
            app.InglsMenu.MenuSelectedFcn = createCallbackFcn(app, @InglsMenuSelected, true);
            app.InglsMenu.Text = 'Inglés';
            app.CastellanoMenu = uimenu(app.IdiomaMenu);
            app.CastellanoMenu.MenuSelectedFcn = createCallbackFcn(app, @CastellanoMenuSelected, true);
            app.CastellanoMenu.Text = 'Castellano';
            app.EuskeraMenu = uimenu(app.IdiomaMenu);
            app.EuskeraMenu.MenuSelectedFcn = createCallbackFcn(app, @EuskeraMenuSelected, true);
            app.EuskeraMenu.Text = 'Euskera';
            % Show the figure after all components are created
            app.SignalVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalVisualizerUIFigure)
        end
    end
end